classdef App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        ControlPanel        matlab.ui.container.Panel
        InactiveButton      matlab.ui.control.Button
        BTO3Button          matlab.ui.control.Button
        BTO2Button          matlab.ui.control.Button
        BTO1Button          matlab.ui.control.Button
        SequenceButton      matlab.ui.control.Button
        BTO3Lamp            matlab.ui.control.Lamp
        BTO3LampLabel       matlab.ui.control.Label
        BTO2Lamp            matlab.ui.control.Lamp
        BTO2LampLabel       matlab.ui.control.Label
        BTO1Lamp            matlab.ui.control.Lamp
        BTO1LampLabel       matlab.ui.control.Label
        ConnectionPanel     matlab.ui.container.Panel
        ConnectButton       matlab.ui.control.StateButton
        Lamp                matlab.ui.control.Lamp
        PortsDropDown       matlab.ui.control.DropDown
        PortsDropDownLabel  matlab.ui.control.Label
        DetectionPanel      matlab.ui.container.Panel
        GCSwitch            matlab.ui.control.Switch
        GCSwitchLabel       matlab.ui.control.Label
        Sensor3Lamp         matlab.ui.control.Lamp
        Sensor3LampLabel    matlab.ui.control.Label
        Sensor2Lamp         matlab.ui.control.Lamp
        Sensor2LampLabel    matlab.ui.control.Label
        Sensor1Lamp         matlab.ui.control.Lamp
        Sensor1LampLabel    matlab.ui.control.Label
        Gauge               matlab.ui.control.Gauge
        UIAxes              matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Serial % Description
    end
    
    methods (Access = private)
        
        function name = loadFile(app,res,id)
            disp(app.GCSwitch.Value)
            Colors = ["blue","yellow","red","black"];
            if app.GCSwitch.Value
                disp("GC ON")
                GC ="GC_OFF";
            else
                disp("GC OFF")
                GC ="GC_ON";
            end
            resSplit = split(res,",");
            res1 = "Res1"+app.num2name(resSplit(2));
            res2 = "Res2"+app.num2name(resSplit(4));
            res3 = "Res3"+app.num2name(resSplit(6));
            name = "./Simulation/"+GC+"/"+res1+"/"+res2+"/"+res3+"/Circuit"+GC+"_"+res1+"_"+res2+"_"+res3;
    
            disp("/Circuit"+GC+"_"+res1+"_"+res2+"_"+res3);
            load(name,"gain","transmition");
            
            laterals = [4500:5500];
            wl = transmition.wavelength(laterals)*10^9;
            gn = gain(laterals);
            
            writeline(app.Serial,"A");
            for i = length(gn):-20:1
                app.Gauge.Value = (length(gn)-i)/length(gn)*100;
                plot(app.UIAxes,wl(i:length(gn)),gn(i:length(gn)),"Color",Colors(id));
                pause(0.0001);
                drawnow;
            end
            writeline(app.Serial,"B");

            if(id==1 && floor(str2num(resSplit(id*2))/2))
                app.Sensor1Lamp.Color = [0 1 0];
            elseif (id==2 && floor(str2num(resSplit(id*2))/2))
                app.Sensor2Lamp.Color = [0 1 0];
            elseif (id==3 && floor(str2num(resSplit(id*2))/2))
                app.Sensor3Lamp.Color = [0 1 0];
            elseif(id==1)
                app.Sensor1Lamp.Color = [1 0 0];
            elseif (id==2)
                app.Sensor2Lamp.Color = [1 0 0];
            elseif (id==3)
                app.Sensor3Lamp.Color = [1 0 0];
            end

        end
        
        function nameCoded = num2name(app,val)
            val = str2num(val);
            if mod(val,2)
                nameCoded = "Active";
            else
                nameCoded = "Inactive";
            end
            if floor(val/2)
                nameCoded = nameCoded+"Detect";
            else
                nameCoded = nameCoded+"Empty";
            end
        end

        function resetLamps(app)
            app.Sensor1Lamp.Color = [0 0 0];
            app.Sensor2Lamp.Color = [0 0 0];
            app.Sensor3Lamp.Color = [0 0 0];
            app.BTO1Lamp.Color = [0 0 0];
            app.BTO2Lamp.Color = [0 0 0];
            app.BTO3Lamp.Color = [0 0 0];
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Drop down opening function: PortsDropDown
        function UIAxesButtonDown(app, event)
            app.PortsDropDown.Items = serialportlist;
            if(length(serialportlist)>0)
                app.Lamp.Color = [0 0 1];
            end
        end

        % Value changed function: ConnectButton
        function ConnectButtonValueChanged(app, event)
            value = app.ConnectButton.Value;
            if(value)
                app.Serial = serialport(app.PortsDropDown.Value,115200);
                app.Lamp.Color = [0 1 0];
            else
                delete(app.Serial);
                app.Lamp.Color = [1 0 0];
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
        end

        % Button pushed function: SequenceButton
        function SequenceButtonPushed(app, event)
            if isequal(app.Lamp.Color,[0 1 0])
                disp("Sequence");
                app.resetLamps();
                cla(app.UIAxes);
                hold(app.UIAxes,"on");
                app.BTO1Lamp.Color = [0 1 0];
                writeline(app.Serial,"D");
                res = readline(app.Serial);
                app.loadFile(res,1);
                writeline(app.Serial,"D");
                res = readline(app.Serial);
                app.BTO1Lamp.Color = [0 0 0];
                app.BTO2Lamp.Color = [0 1 0];
                writeline(app.Serial,"E");
                res = readline(app.Serial);
                app.loadFile(res,2);
                writeline(app.Serial,"E");
                res = readline(app.Serial);
                app.BTO2Lamp.Color = [0 0 0];
                app.BTO3Lamp.Color = [0 1 0];
                writeline(app.Serial,"F");
                res = readline(app.Serial);
                app.loadFile(res,3);
                writeline(app.Serial,"F");
                res = readline(app.Serial);
                app.BTO3Lamp.Color = [0 0 0];
                hold(app.UIAxes,"off");
            end
        end

        % Button pushed function: BTO1Button
        function BTO1ButtonPushed(app, event)
            if isequal(app.Lamp.Color,[0 1 0])
                disp("BTO1");
                app.resetLamps();
                app.BTO1Lamp.Color = [0 1 0];
                cla(app.UIAxes);
                writeline(app.Serial,"D");
                res = readline(app.Serial);
                app.loadFile(res,1);
                app.BTO1Lamp.Color = [0 0 0];
            end
        end

        % Button pushed function: BTO2Button
        function BTO2ButtonPushed(app, event)
            if isequal(app.Lamp.Color,[0 1 0])
                disp("BTO2");
                app.resetLamps();
                app.BTO2Lamp.Color = [0 1 0];
                cla(app.UIAxes);
                writeline(app.Serial,"E");
                res = readline(app.Serial);
                app.loadFile(res,2);
                writeline(app.Serial,"E");
                res = readline(app.Serial);
                app.BTO2Lamp.Color = [0 0 0];
            end
        end

        % Button pushed function: BTO3Button
        function BTO3ButtonPushed(app, event)
            if isequal(app.Lamp.Color,[0 1 0])
                disp("BTO3");
                app.resetLamps();
                app.BTO3Lamp.Color = [0 1 0];
                cla(app.UIAxes);
                writeline(app.Serial,"F");
                res = readline(app.Serial);
                app.loadFile(res,3);
                writeline(app.Serial,"F");
                res = readline(app.Serial);
                app.BTO3Lamp.Color = [0 0 0];
            end
        end

        % Button pushed function: InactiveButton
        function InactiveButtonPushed(app, event)
            if isequal(app.Lamp.Color,[0 1 0])
                disp("Inactive");
                app.resetLamps();
                cla(app.UIAxes);
                writeline(app.Serial,"C");
                res = readline(app.Serial);
                app.loadFile(res,4);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.DetectionPanel = uipanel(app.UIFigure);
            app.DetectionPanel.Title = 'Detection';
            app.UIAxes = uiaxes(app.DetectionPanel);
            xlabel(app.UIAxes, 'Wavelength(nm)')
            ylabel(app.UIAxes, 'Gain')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [1543 1553];
            app.UIAxes.YLim = [-1.2 0];
            app.Gauge = uigauge(app.DetectionPanel, 'circular');
            app.Sensor1LampLabel = uilabel(app.DetectionPanel);
            app.Sensor1LampLabel.Text = 'Sensor 1';
            app.Sensor1Lamp = uilamp(app.DetectionPanel);
            app.Sensor2LampLabel = uilabel(app.DetectionPanel);
            app.Sensor2LampLabel.Text = 'Sensor 2';
            app.Sensor2Lamp = uilamp(app.DetectionPanel);
            app.Sensor3LampLabel = uilabel(app.DetectionPanel);
            app.Sensor3LampLabel.Text = 'Sensor 3';
            app.Sensor3Lamp = uilamp(app.DetectionPanel);
            app.GCSwitchLabel = uilabel(app.DetectionPanel);
            app.GCSwitchLabel.Text = 'GC';
            app.GCSwitch = uiswitch(app.DetectionPanel, 'slider');
            app.ConnectionPanel = uipanel(app.UIFigure);
            app.ConnectionPanel.Title = 'Connection';
            app.PortsDropDownLabel = uilabel(app.ConnectionPanel);
            app.PortsDropDownLabel.Text = 'Ports';
            app.PortsDropDown = uidropdown(app.ConnectionPanel);
            app.PortsDropDown.Items = {'Connection'};
            app.PortsDropDown.DropDownOpeningFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.PortsDropDown.Value = 'Connection';
            app.Lamp = uilamp(app.ConnectionPanel);
            app.ConnectButton = uibutton(app.ConnectionPanel, 'state');
            app.ConnectButton.ValueChangedFcn = createCallbackFcn(app, @ConnectButtonValueChanged, true);
            app.ConnectButton.Text = 'Connect';
            app.ControlPanel = uipanel(app.UIFigure);
            app.ControlPanel.Title = 'Control';
            app.BTO1LampLabel = uilabel(app.ControlPanel);
            app.BTO1LampLabel.Text = 'BTO 1';
            app.BTO1Lamp = uilamp(app.ControlPanel);
            app.BTO2LampLabel = uilabel(app.ControlPanel);
            app.BTO2LampLabel.Text = 'BTO 2';
            app.BTO2Lamp = uilamp(app.ControlPanel);
            app.BTO3LampLabel = uilabel(app.ControlPanel);
            app.BTO3LampLabel.Text = 'BTO 3';
            app.BTO3Lamp = uilamp(app.ControlPanel);
            app.SequenceButton = uibutton(app.ControlPanel, 'push');
            app.SequenceButton.ButtonPushedFcn = createCallbackFcn(app, @SequenceButtonPushed, true);
            app.SequenceButton.Text = 'Sequence';
            app.BTO1Button = uibutton(app.ControlPanel, 'push');
            app.BTO1Button.ButtonPushedFcn = createCallbackFcn(app, @BTO1ButtonPushed, true);
            app.BTO1Button.Text = 'BTO 1';
            app.BTO2Button = uibutton(app.ControlPanel, 'push');
            app.BTO2Button.ButtonPushedFcn = createCallbackFcn(app, @BTO2ButtonPushed, true);
            app.BTO2Button.Text = 'BTO 2';
            app.BTO3Button = uibutton(app.ControlPanel, 'push');
            app.BTO3Button.ButtonPushedFcn = createCallbackFcn(app, @BTO3ButtonPushed, true);
            app.BTO3Button.Text = 'BTO 3';
            app.InactiveButton = uibutton(app.ControlPanel, 'push');
            app.InactiveButton.ButtonPushedFcn = createCallbackFcn(app, @InactiveButtonPushed, true);
            app.InactiveButton.Text = 'Inactive';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end